classdef DMD_Diffraction_Efficiency_Calculator_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnhanceSlider                   matlab.ui.control.Slider
        EnhanceSliderLabel              matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ParameterSweepButton            matlab.ui.control.Button
        GaussianFactorEditField         matlab.ui.control.NumericEditField
        GaussianFactorEditFieldLabel    matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        DiamondButton                   matlab.ui.control.RadioButton
        ManhattanButton                 matlab.ui.control.RadioButton
        PupilDropDown                   matlab.ui.control.DropDown
        PupilDropDownLabel              matlab.ui.control.Label
        WavelengthnmLabel               matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        StepLabel                       matlab.ui.control.Label
        StopLabel                       matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        PhotopicDiffractionEfficiencyTextArea  matlab.ui.control.TextArea
        AverageDiffractionEfficiencyTextArea  matlab.ui.control.TextArea
        RunButton                       matlab.ui.control.Button
        SaveDiffractionPatternDataCheckBox  matlab.ui.control.CheckBox
        OutputFileNameEditFieldLabel    matlab.ui.control.Label
        PowerRatioResultLabel           matlab.ui.control.Label
        PhotopicDiffractionEfficiencyTextAreaLabel  matlab.ui.control.Label
        OutputFileNameEditField         matlab.ui.control.EditField
        FProjectionStepEditField        matlab.ui.control.NumericEditField
        FProjectionStopEditField        matlab.ui.control.NumericEditField
        FProjectionEditField            matlab.ui.control.NumericEditField
        FProjectionEditFieldLabel       matlab.ui.control.Label
        TiltAngleLabel                  matlab.ui.control.Label
        FIlluminationStepEditField      matlab.ui.control.NumericEditField
        FIlluminationStopEditField      matlab.ui.control.NumericEditField
        FIlluminationEditField          matlab.ui.control.NumericEditField
        FLabel                          matlab.ui.control.Label
        TiltAngleStepEditField          matlab.ui.control.NumericEditField
        TiltAngleStopEditField          matlab.ui.control.NumericEditField
        TiltAngleEditField              matlab.ui.control.NumericEditField
        IlluminationAngleStepEditField  matlab.ui.control.NumericEditField
        IlluminationAngleStopEditField  matlab.ui.control.NumericEditField
        IlluminationAngleEditField      matlab.ui.control.NumericEditField
        IlluminationAngleLabel          matlab.ui.control.Label
        WavelengthStepnmEditField       matlab.ui.control.NumericEditField
        EndWavelengthnmEditField        matlab.ui.control.NumericEditField
        StartWavelengthnmEditField      matlab.ui.control.NumericEditField
        ArraySizeEditField              matlab.ui.control.NumericEditField
        ArraySizeEditFieldLabel         matlab.ui.control.Label
        PixelModelLabel                 matlab.ui.control.Label
        PixelDropDown                   matlab.ui.control.DropDown
        TIDLPDiffractionModelingAppv2Label  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = public)
        tilt= 14.5; % Description
        pitch= 4.5; % Description
        mirrorSize = 4.05; % Description
        illum_theta = 29;
        Illum_phi_1=-45;
        gain_boost = 1;
        diff_patt1;% = zeros(201,201,3);
        f_num_proj1 = 2.4;
        gaussian = 0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartWavelengthnmEditField
        function StartWavelengthnmEditFieldValueChanged(app, event)
            value = app.StartWavelengthnmEditField.Value;
        end

        % Value changed function: EndWavelengthnmEditField
        function EndWavelengthnmEditFieldValueChanged(app, event)
            value = app.EndWavelengthnmEditField.Value;
        end

        % Callback function
        function TiltSwitchValueChanged(app, event)

        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.PixelDropDown.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%        The following Matlab DMD Diffraction model was developed at Texas
%        Instruments, Inc. The program uses a scalar
%        diffraction model approximation to estimate the diffraction
%        efficiency of a telecentric optical system as a function of a
%        number of DMD and illumination parameters. The solution is not
%        exact, but has been shown to give good agreement with measured
%        data. 
%

close all;
array_size = app.ArraySizeEditField.Value;
deg2rad=pi/180;
rad2deg = 180/pi;
diff_eff_matrix=zeros([],2);
diff_patt=zeros(array_size,array_size,3);
f_num_u_lower = array_size+100;
f_num_u_upper = 0;
f_num_v_lower = array_size+100;
f_num_v_upper = 0;
counter = 1;

file_name = [app.OutputFileNameEditField.Value ,'.xlsx'];

delete(file_name);

valueNames = {'Pitch', 'Gaussian Factor', 'AOI', 'Mirror Tilt', 'Illumination f/#', 'Projection f/#', 'Average Diffraction Efficiency', 'Average Photopic Diffraction Efficiency'};
numValues = numel(valueNames);
dataCell = cell(numValues + 1, counter + 1);
dataCell(2:end, 1) = valueNames;

if(app.TiltAngleStopEditField.Visible == false)
    app.TiltAngleStopEditField.Value = app.TiltAngleEditField.Value;
    app.FIlluminationStopEditField.Value = app.FIlluminationEditField.Value;
    app.FProjectionStopEditField.Value = app.FProjectionEditField.Value;
    app.IlluminationAngleStopEditField.Value = app.IlluminationAngleEditField.Value;
end

%%%%%%%%% Inputs: %%%%%%%%

if app.FIlluminationStepEditField.Value == 0
    app.FIlluminationStepEditField.Value = 0.1;
end
if app.FProjectionStepEditField.Value == 0
    app.FProjectionStepEditField.Value = 0.1;
end
if app.IlluminationAngleStepEditField.Value == 0
    app.IlluminationAngleStepEditField.Value = 0.1;
end
if app.TiltAngleStepEditField.Value == 0
    app.TiltAngleStepEditField.Value = 0.1;
end

if app.FIlluminationStopEditField.Value < app.FIlluminationEditField.Value
    app.FIlluminationStopEditField.Value = app.FIlluminationEditField.Value;
end
if app.FProjectionStopEditField.Value < app.FProjectionEditField.Value
    app.FProjectionStopEditField.Value = app.FProjectionEditField.Value;
end
if app.IlluminationAngleStopEditField.Value < app.IlluminationAngleEditField.Value
    app.IlluminationAngleStopEditField.Value = app.IlluminationAngleEditField.Value;
end
if app.TiltAngleStopEditField.Value < app.TiltAngleEditField.Value
    app.TiltAngleStopEditField.Value = app.TiltAngleEditField.Value;

end

for f_num_cone = app.FIlluminationEditField.Value:app.FIlluminationStepEditField.Value:app.FIlluminationStopEditField.Value
for f_num_proj=  app.FProjectionEditField.Value:app.FProjectionStepEditField.Value:app.FProjectionStopEditField.Value
for Illum_theta = app.IlluminationAngleEditField.Value:app.IlluminationAngleStepEditField.Value:app.IlluminationAngleStopEditField.Value
for DMD_eff_tilt_original = app.TiltAngleEditField.Value:app.TiltAngleStepEditField.Value:app.TiltAngleStopEditField.Value

% f_num_proj= app.FProjectionEditField.Value;  % projection F/number
app.f_num_proj1 = app.FProjectionEditField.Value;
% f_num_cone= app.FIlluminationEditField.Value;   % illumination F/number
DMD_pitch_x= app.pitch;% Pitch in X direciton
DMD_pitch_y= app.pitch; % Pitch in Y direction
DMD_mirror_size= app.mirrorSize; % Mirror size

DMD_eff_az=45.0+180;  % for non-TRP
Illum_phi= app.Illum_phi_1;

wavelength_lower= app.StartWavelengthnmEditField.Value/1000; %0.400; % um
wavelength_higher= app.EndWavelengthnmEditField.Value/1000; %0.700; % um
wavelength_delta= app.WavelengthStepnmEditField.Value/1000; % um

if wavelength_higher == wavelength_lower
    wavelength_lower = wavelength_lower-0.0001;
    wavelength_higher = wavelength_higher + 0.0001;
end
if (wavelength_higher-wavelength_lower) <  wavelength_delta
    wavelength_delta = (wavelength_higher-wavelength_lower);
end
if wavelength_delta == 0
    wavelength_delta = 0.0001;
end

counter = counter + 1;
if(DMD_eff_tilt_original == 0)
    DMD_eff_tilt_original = 0.0001;
end

%TRP Only

if strcmp(app.PixelDropDown.Value, '5.4 TRP')
    %TRP
%     DMD_eff_tilt = app.TiltAngleEditField.Value; % + tilt_tolerance;
  if(DMD_eff_tilt_original > 0)
    DMD_pitch_ang=  DMD_eff_tilt_original*.751851; % 13 pitch and 11.5 roll typical
    DMD_roll= DMD_eff_tilt_original*.665099;
    DMD_eff_tilt=sin(acos(cos(DMD_pitch_ang*deg2rad)*cos(-DMD_roll*deg2rad)));  % sine correction to get correct grating blaze
    DMD_eff_az=atan(tan(-DMD_roll*deg2rad)/sin(DMD_pitch_ang*deg2rad))-pi/4;

  elseif(DMD_eff_tilt_original < 0)
    DMD_pitch_ang= -DMD_eff_tilt_original*.764706; % 13 pitch and 11.5 roll typical
    DMD_roll= .676471*DMD_eff_tilt_original;
    DMD_eff_tilt= sin(acos(cos(DMD_pitch_ang*deg2rad)*cos(-DMD_roll*deg2rad)));  
    DMD_eff_az= atan(tan(-DMD_roll*deg2rad)/sin(DMD_pitch_ang*deg2rad))-pi/4;
  
  end
   
else
    %Torsional
    DMD_eff_tilt=DMD_eff_tilt_original*deg2rad;
    DMD_eff_az=DMD_eff_az*deg2rad;
end

proj_cone_ang=asin(1/(2*f_num_proj))/deg2rad;
cone_ang=asin(1/(2*f_num_cone))/deg2rad; 

% Define wavelength range
lambda_indx=0;  
wb = waitbar(0, 'Loading...');

for lambda=wavelength_lower:wavelength_delta:wavelength_higher
    lambda_indx=lambda_indx+1;
    lambda;
    proj_num=0;
    waitbar((lambda_indx)/151);

% Calculate mirror normal using direction cosines  (temporary)
mirror_normal=[sin(DMD_eff_tilt)*cos(DMD_eff_az);sin(DMD_eff_tilt)*sin(DMD_eff_az);cos(DMD_eff_tilt)];

% % Anglular sampling resolution
% del_angle=.5; % normally 0.5 deg!

% Anglular sampling resolution
del_angle=1.0 / max(f_num_cone,f_num_proj); % was 0.5 deg


% Rotation around illumination angle. Used to calculate illumination direction cosine. 
roty=[cos(-Illum_theta*deg2rad) 0 -sin(-Illum_theta*deg2rad);0 1 0;sin(-Illum_theta*deg2rad) 0 cos(-Illum_theta*deg2rad)];
rotz=[cos(Illum_phi*deg2rad) sin(Illum_phi*deg2rad) 0;-sin(Illum_phi*deg2rad) cos(Illum_phi*deg2rad) 0; 0 0 1];
cone_radius=sin(cone_ang*deg2rad);
del_angle=sin(del_angle*deg2rad);
 
diff_patt_single=zeros(array_size,array_size); % Single diffraction order array
diff_patt_lambda=zeros(array_size,array_size); % Final diffraction pattern array for superposition of all diffraction orders

% Loop through incident cone angles
    for ang_x=-cone_radius:del_angle:cone_radius
        for ang_y=-sqrt(cone_radius^2-ang_x^2):del_angle:sqrt(cone_radius^2-ang_x^2)
            
            vec1=[ang_x;ang_y;sqrt(1-ang_x^2-ang_y^2)];
            
            dir_cos_illum=rotz*roty*vec1;
            
            % Apodization 
            if strcmp(app.PupilDropDown.Value, 'Gaussian')
                gaussian_val = app.GaussianFactorEditField.Value;
                if gaussian_val < 0
                    gaussian_val = 0;
                elseif gaussian_val > 50000
                    gaussian_val = 500000;
                end
                radius = sqrt(ang_x^2 + ang_y^2);
                weight = exp(-gaussian_val .* (radius./cone_radius).^2);
            elseif strcmp(app.PupilDropDown.Value, 'Uniform')
                weight = 1;
                app.gaussian = 0;
                gaussian_val = 0;
            end

            % Calculate range of diffraction orders within unit circle- bounding  % rectangle is easier
            order_m_min=-round(2*DMD_pitch_x/lambda);
            order_m_max=round(2*DMD_pitch_x/lambda);
            order_n_min=-round(2*DMD_pitch_y/lambda);
            order_n_max=round(2*DMD_pitch_y/lambda);

            % Scan through diffraction orders
            for order_m=order_m_min:order_m_max
                for order_n=order_n_min:order_n_max
                    
                     % Correlates to alpha and beta in Harvey equations.Diffraction order propogation vector
                     u=dir_cos_illum(1)+order_m*lambda/DMD_pitch_x;
                     v=dir_cos_illum(2)+order_n*lambda/DMD_pitch_y;
                     w=sqrt(1-u^2-v^2);
                     
                     sinc_arg_u=(order_m*lambda/DMD_pitch_x-2*mirror_normal(1)*sqrt(1-mirror_normal(1)^2-mirror_normal(2)^2))*DMD_mirror_size/lambda; % with sqrt(1-u^2-v^2) term
                     sinc_arg_v=(order_n*lambda/DMD_pitch_y-2*mirror_normal(2)*sqrt(1-mirror_normal(1)^2-mirror_normal(2)^2))*DMD_mirror_size/lambda; % with sqrt(1-u^2-v^2) term            
                
                       if (u^2+v^2 < 1.0) 
                           diff_patt_single=weight*((sin(pi*sinc_arg_u)/(pi*sinc_arg_u))*(sin(pi*sinc_arg_v)/(pi*sinc_arg_v)))^2*lambda^2; 
                           diff_patt_lambda(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)))=diff_patt_lambda(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)))+diff_patt_single;
                                  if(lambda <.500 & lambda > 0)
                                      diff_patt(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)),3)=diff_patt(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)),3)+diff_patt_single;
                              elseif(lambda <.600 & lambda > .499)
                                      diff_patt(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)),2)=diff_patt(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)),2)+diff_patt_single;
                              elseif(lambda < 9 & lambda > .599)
                                      diff_patt(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)),1)=diff_patt(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)),1)+diff_patt_single;
                                  end

                       end                            
                end
            end % diff orders  
        end
    end % loop on illumination cone
    
% Calculating input and output power
proj_pwr=0;
tot_pwr=0;

    for rows=1:array_size
        for cols=1:array_size
            
            u=(rows-((array_size/2)+.5))/((array_size-1)/2);
            v=(cols-((array_size/2)+.5))/((array_size-1)/2);
            
            if (u^2+v^2 < 1.0) 

                w=sqrt(1-u^2-v^2);
            
                tot_pwr=tot_pwr+diff_patt_lambda(rows,cols);
            
                if (w > cos(proj_cone_ang*deg2rad))
                    proj_pwr=proj_pwr+diff_patt_lambda(rows,cols);
                    proj_num=proj_num+1;
                end

                if (w < cos(proj_cone_ang*deg2rad) & w > 0.998*cos(proj_cone_ang*deg2rad))
%                     diff_patt(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)),1)=1e9;
%                     diff_patt(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)),2)=1e9;
%                     diff_patt(round(u*((array_size-1)/2)+((array_size/2)+.5)),round(v*((array_size-1)/2)+((array_size/2)+.5)),3)=1e9;  
                
                    f_num_u = round(u*((array_size-1)/2)+((array_size/2)+.5));
                    f_num_v = round(v*((array_size-1)/2)+((array_size/2)+.5));
                    if(f_num_u > f_num_u_upper)
                        f_num_u_upper = f_num_u;
                    end
                    if(f_num_u < f_num_u_lower)
                        f_num_u_lower = f_num_u;
                    end  
                    if(f_num_v > f_num_v_upper)
                        f_num_v_upper = f_num_v;
                    end
                    if(f_num_v < f_num_v_lower)
                        f_num_v_lower = f_num_v;
                    end
                end
            end % if
        end
     end  % rows 
 
    diff_eff_matrix(lambda_indx,counter)=proj_pwr/tot_pwr;
    diff_eff_matrix(lambda_indx,1)=lambda*1000;

illum = Illum_theta;
tilt = DMD_eff_tilt_original;

 f_illum = f_num_cone;
 f_proj = f_num_proj;

str{1,counter} = sprintf('%.4f, %.4f, %.4f, %.4f', illum, tilt, f_illum, f_proj);


if counter == 2 
    str1{1,counter-1}  = ('Wavelength (nm)');
    str1{1,counter} = ('AOI, Tilt, Illum. f/#, Proj. f/#');
elseif counter > 2
    str1{1,counter-1} = ('AOI, Tilt, Illum. f/#, Proj. f/#');
    str1{1,counter} = ('AOI, Tilt, Illum. f/#, Proj. f/#');
end

if counter == 2
    str2{1,counter} = sprintf('Pitch: %.1f', DMD_pitch_x);
elseif counter > 2
    str2{1, counter} = sprintf('  ');
end

str4{1,counter} = sprintf('AOI %.1f, Tilt %.1f, F%.1f_%.1f', illum, tilt, f_illum, f_proj);
  
end  % for lambda

avg_diff_eff(counter) = mean(diff_eff_matrix(:,counter));
app.AverageDiffractionEfficiencyTextArea.Value = string(avg_diff_eff(counter));


if (app.DiamondButton.Value == 1)
    diff_patt = imrotate(diff_patt, 45, 'bilinear', 'crop');
end

% Display Diffraction Pattern
imshow(diff_patt*app.gain_boost,[], 'Parent',app.UIAxes);

% Setup Efficiency Plot
app.UIAxes.XLim = [0 width(diff_patt)];
app.UIAxes.YLim = [0 height(diff_patt)];
[rows, columns, numberOfColorChannels] = size(diff_patt);
x = columns/2;
y = rows/2;
centx = (x+(array_size/2+1)) / 2;
centy = (y+(array_size/2+1)) / 2;
r = ((array_size/4)+((array_size-1)/100))/f_num_proj;
hold(app.UIAxes, "on");
theta = 0 : (2 * pi / 10000) : (2 * pi);
pline_x = r * cos(theta) + centx;
pline_y = r * sin(theta) + centy;
plot(pline_x, pline_y, '--', 'MarkerFaceColor', 'b',  'LineWidth', 1.5, 'Parent', app.UIAxes); %[0 0.4470 0.7410]
hold(app.UIAxes, "off");
delete(wb); 

% Print Diffraction Summary to text file
phot=dlmread('photopic.txt');
diff_eff_interp=interp1(diff_eff_matrix(1:lambda_indx,1),diff_eff_matrix(1:lambda_indx,counter),phot(:,1),'spline');
diff_eff_phot(counter)=mean(diff_eff_interp.*phot(:,2))/mean(phot(:,2));

if diff_eff_phot(counter) < 0 || diff_eff_phot(counter) > 1 
    app.PhotopicDiffractionEfficiencyTextArea.Value = 'NA';
else
   app.PhotopicDiffractionEfficiencyTextArea.Value = string(diff_eff_phot(counter));
end 

avg_diff_eff(counter);
values1 = [DMD_pitch_x, gaussian_val, illum, tilt, f_illum, f_proj, avg_diff_eff(counter), diff_eff_phot(counter)];
dataCell(2:end, counter) = num2cell(values1');

% Plot Diffraction Efficiency
plot(app.UIAxes2, diff_eff_matrix((1:lambda_indx),1), diff_eff_matrix((1:lambda_indx),counter), 'LineWidth',2, 'DisplayName', ['Illum Angle: ', num2str(Illum_theta), ', Tilt: ', num2str(DMD_eff_tilt_original), ', F Illum: ', num2str(f_num_cone), ', F Proj: ', num2str(f_num_proj)]);
app.UIAxes2.YLim = [0 1];
hold(app.UIAxes2, "on");
legend(app.UIAxes2, "show", 'Location', 'southeast');

delete(wb);
app.diff_patt1 = zeros(array_size,array_size,3);
app.diff_patt1 = diff_patt(:,:,:);

x_dc_lower = (f_num_u_lower-101);
x_dc_upper = (f_num_u_upper-101);
y_dc_lower = (f_num_v_lower-101);
y_dc_upper = (f_num_v_upper-101);

diff_patt_total = diff_patt(f_num_u_lower:f_num_u_upper,f_num_u_lower:f_num_u_upper,1) + diff_patt(f_num_u_lower:f_num_u_upper,f_num_u_lower:f_num_u_upper,2) + diff_patt(f_num_u_lower:f_num_u_upper,f_num_u_lower:f_num_u_upper,3);
[X,Y] = meshgrid(x_dc_lower:x_dc_upper,y_dc_lower:y_dc_upper);
normalized_diff_patt_total = diff_patt_total./max(max(diff_patt_total));
surf(app.UIAxes3, X,Y,normalized_diff_patt_total)
app.UIAxes3.YLim = [-cone_ang-25, cone_ang+25];
app.UIAxes3.XLim = [-cone_ang-25, cone_ang+25];
% xlabel('Angle');
% ylabel('Angle');
zlabel(app.UIAxes3,'Normalized Energy in Pupil');
% app.UIAxes3.Zlim = [f_num]

filename_output1= [app.OutputFileNameEditField.Value, '_Diffraction_Pattern', '.xlsx'];

diff_patt_total = diff_patt(:,:,1) + diff_patt(:,:,2) + diff_patt(:,:,3);
if app.SaveDiffractionPatternDataCheckBox.Value == 1
     writematrix( diff_patt_total, filename_output1, 'Sheet', str4{1,counter} );
%      xlswrite(filename_output1,diff_patt_total, str4);


end


end
end
end
end
hold(app.UIAxes2, "off");
% 
% if app.OutputFileNameEditField.Value == 
%     writecell(str2, 'Diffraction_Efficiency_Data_1.xlsx');
% else
    % writecell(str2, [app.OutputFileNameEditField.Value, '.xlsx']);
% end

%if (gaussian_val >= 0)

radius_accumulation = [];
counter = 0;
    for ang_x=-cone_radius:del_angle:cone_radius
        for ang_y=-sqrt(cone_radius^2-ang_x^2):del_angle:sqrt(cone_radius^2-ang_x^2)
            
            counter = counter + 1;
            % Gaussian Apodization Only
            radius = sqrt(ang_x^2 + ang_y^2);
            weight = exp(-gaussian_val .* (radius./ cone_radius).^2);
            radius_accumulation(counter) = (radius*rad2deg);
            weight_accumulation(counter) = weight;
        end
    end

h = cone_ang;
g = weight_accumulation(end);

ang = [-cone_radius:2*cone_radius/100:cone_radius];
               
plot(app.UIAxes4, ang.*rad2deg, exp(-gaussian_val .* (ang./cone_radius).^2),'b');

% plot(app.UIAxes4, radius_accumulation, weight_accumulation, 'b')
hold(app.UIAxes4, "on");
% plot(app.UIAxes4, -radius_accumulation, weight_accumulation, 'b')
plot(app.UIAxes4, [h h], [0 g], 'k');
plot(app.UIAxes4, [-h -h], [0 g], 'k');
hold(app.UIAxes4, "off");
app.UIAxes4.YLim = [0, 1.1];
app.UIAxes4.XLim = [-cone_ang-5, cone_ang+5];

%end


% 
% writematrix(app.PixelDropDown.Value, file_name, 'Sheet', 'Diffraction Efficiency Data');
% writecell(dataCell, file_name, 'Sheet', 'Diffraction Efficiency Data', 'WriteMode', 'append');
% writematrix('Wavelength', file_name, 'Sheet', 'Diffraction Efficiency Data', 'WriteMode','append')
% writematrix(diff_eff_matrix, file_name, 'Sheet', 'Diffraction Efficiency Data', 'WriteMode','append');
% 

ang_1 = ang.*rad2deg;
ang_1 = ang_1';
rad_1 = exp(-gaussian_val .* (ang./cone_radius).^2);
rad_1 = rad_1';
dataCell2 = [ang_1, rad_1];
% writecell(dataCell2, file_name, 'Sheet', 'Apodization Profile', 'WriteMode','append');

pitch_val = sprintf('Pitch: %.1f', app.PixelDropDown.Value);

% % === Sheet 1: "Diffraction Efficiency Data" ===
% % % Write dropdown value (single value)
% writematrix(DMD_pitch_x, file_name, ...
%     'Sheet', 'Diffraction Efficiency Data', 'Range', 'A1');

% Append data cell
writecell(dataCell, file_name, ...
    'Sheet', 'Diffraction Efficiency Data', ...
    'WriteMode', 'append');

% Append a label
writematrix("Wavelength", file_name, ...
    'Sheet', 'Diffraction Efficiency Data', ...
    'WriteMode', 'append');

% Append a matrix
writematrix(diff_eff_matrix, file_name, ...
    'Sheet', 'Diffraction Efficiency Data', ...
    'WriteMode', 'append');

% % === Sheet 2: "Apodization Profile" ===
% dataCell2 = [ang.*rad2deg, exp(-gaussian_val .* (ang./cone_radius).^2)];

writematrix("Pupil Cross Section Apodization(degrees)", file_name, ...
    'Sheet', 'Apodization Profile', ...
    'Range', 'A1');

writematrix("Relative Intensity", file_name, ...
    'Sheet', 'Apodization Profile', ...
    'Range', 'B1');


writematrix(dataCell2, file_name, ...
    'Sheet', 'Apodization Profile', ...
    'WriteMode', 'append');


app.gaussian = 0;
app.EnhanceSlider.Value = 1;

        end

        % Value changed function: PixelDropDown
        function PixelDropDownValueChanged(app, event)
            value = app.PixelDropDown.Value;

            if strcmp(value, '5.4 TRP')
                app.pitch = 5.4;
                app.mirrorSize = 4.95;
%                 app.tilt = 17;
%                 app.illum_theta = 34;
                app.Illum_phi_1 = -90;
                app.IlluminationAngleEditField.Value = 34;
                app.IlluminationAngleStopEditField.Value = 34;
                app.TiltAngleEditField.Value = 17;
                app.TiltAngleStopEditField.Value = 17;
                app.FIlluminationEditField.Value = 1.7;
                app.FIlluminationStopEditField.Value = 1.7;
                app.FProjectionEditField.Value = 1.7;
                app.FProjectionStopEditField.Value = 1.7;
               elseif strcmp(value, '4.5 RDP')
                app.pitch = 4.5;
                app.mirrorSize = 4.05;
%                 app.tilt = 17;
%                 app.illum_theta = 34;
                app.Illum_phi_1 = -45;
                app.IlluminationAngleEditField.Value = 29;
                app.IlluminationAngleStopEditField.Value = 29;
                app.TiltAngleEditField.Value = 14.5;
                app.TiltAngleStopEditField.Value = 14.5;
                app.FIlluminationEditField.Value = 2;
                app.FIlluminationStopEditField.Value = 2;
                app.FProjectionEditField.Value = 2;
                app.FProjectionStopEditField.Value = 2;
              elseif strcmp(value, '5.4 SST, Tilt 14.5')
                app.pitch = 5.4;
                app.mirrorSize = 4.95;
%                 app.tilt = 17;
%                 app.illum_theta = 34;
                app.Illum_phi_1 = -45;
                app.IlluminationAngleEditField.Value = 29;
                app.IlluminationAngleStopEditField.Value = 29;
                app.TiltAngleEditField.Value = 14.5;
                app.TiltAngleStopEditField.Value = 14.5;
                app.FIlluminationEditField.Value = 2;
                app.FIlluminationStopEditField.Value = 2;
                app.FProjectionEditField.Value = 2;
                app.FProjectionStopEditField.Value = 2;
              elseif strcmp(value, '5.4 SST, Tilt 12')
                app.pitch = 5.4;
                app.mirrorSize = 4.95;
%                 app.tilt = 17;
%                 app.illum_theta = 34;
                app.Illum_phi_1 = -45;
                app.IlluminationAngleEditField.Value = 24;
                app.IlluminationAngleStopEditField.Value = 24;
                app.TiltAngleEditField.Value = 12;
                app.TiltAngleStopEditField.Value = 12;
                app.FIlluminationEditField.Value = 2.4;
                app.FIlluminationStopEditField.Value = 2.4;
                app.FProjectionEditField.Value = 2.4;
                app.FProjectionStopEditField.Value = 2.4;
              elseif strcmp(value, '7.56 VSP') 
                app.pitch = 7.56;
                app.mirrorSize = 7.06;
%                 app.tilt = 12;
%                 app.illum_theta = 24;
                app.Illum_phi_1 = -45;
                app.IlluminationAngleEditField.Value = 24;
                app.IlluminationAngleStopEditField.Value = 24;
                app.TiltAngleEditField.Value = 12;
                app.TiltAngleStopEditField.Value = 12;
                app.FIlluminationEditField.Value = 2.4;
                app.FIlluminationStopEditField.Value = 2.4;
                app.FProjectionEditField.Value = 2.4;
                app.FProjectionStopEditField.Value = 2.4;
              elseif strcmp(value, '7.637 VSP') 
                app.pitch = 7.637;
                app.mirrorSize = 7.137;
%                 app.tilt = 12;
%                 app.illum_theta = 24;
                app.Illum_phi_1 = -45;
                app.IlluminationAngleEditField.Value = 24;
                app.IlluminationAngleStopEditField.Value = 24;
                app.TiltAngleEditField.Value = 12;
                app.TiltAngleStopEditField.Value = 12;
                app.FIlluminationEditField.Value = 2.4;
                app.FIlluminationStopEditField.Value = 2.4;
                app.FProjectionEditField.Value = 2.4;
                app.FProjectionStopEditField.Value = 2.4;
             elseif strcmp(value, '9 HEP')
                app.pitch = 9;
                app.mirrorSize = 8.5;
%                 app.tilt = 14.5;
%                 app.illum_theta = 29;
                 app.Illum_phi_1 = -45;
                app.IlluminationAngleEditField.Value = 29;
                app.IlluminationAngleStopEditField.Value = 29;
                app.TiltAngleEditField.Value = 14.5;
                app.TiltAngleStopEditField.Value = 14.5;
                app.FIlluminationEditField.Value = 2;
                app.FIlluminationStopEditField.Value = 2;
                app.FProjectionEditField.Value = 2;
                app.FProjectionStopEditField.Value = 2;
              elseif strcmp(value, '10.8 SPD') 
                app.pitch = 10.8;
                app.mirrorSize = 10.08;
%                 app.tilt = 12;
%                 app.illum_theta = 24;
                app.Illum_phi_1 = -45;
                app.IlluminationAngleEditField.Value = 24;
                app.IlluminationAngleStopEditField.Value = 24;
                app.TiltAngleEditField.Value = 12;
                app.TiltAngleStopEditField.Value = 12;
                app.FIlluminationEditField.Value = 2.4;
                app.FIlluminationStopEditField.Value = 2.4;
                app.FProjectionEditField.Value = 2.4;
                app.FProjectionStopEditField.Value = 2.4;
              elseif strcmp(value, '13.68 FTP')
                app.pitch = 13.68;
                app.mirrorSize = 12.9;
%                 app.tilt = 12;
%                 app.illum_theta = 24;
                 app.Illum_phi_1 = -45;
                app.IlluminationAngleEditField.Value = 24;
                app.IlluminationAngleStopEditField.Value = 24;
                app.TiltAngleEditField.Value = 12;
                app.TiltAngleStopEditField.Value = 12;
                app.FIlluminationEditField.Value = 2.4;
                app.FIlluminationStopEditField.Value = 2.4;
                app.FProjectionEditField.Value = 2.4;
                app.FProjectionStopEditField.Value = 2.4;
              end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: FIlluminationEditField
        function FIlluminationEditFieldValueChanged(app, event)
            value = app.FIlluminationEditField.Value;  
        end

        % Value changed function: AverageDiffractionEfficiencyTextArea
        function AverageDiffractionEfficiencyTextAreaValueChanged(app, event)
            value = app.AverageDiffractionEfficiencyTextArea.Value;  
        end

        % Value changed function: FProjectionEditField
        function FProjectionEditFieldValueChanged(app, event)
            value = app.FProjectionEditField.Value;  
        end

        % Value changed function: WavelengthStepnmEditField
        function WavelengthStepnmEditFieldValueChanged(app, event)
            value = app.WavelengthStepnmEditField.Value;  
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            plot(app.UIAxes2, diff_eff_matrix((1:lambda_indx),1), diff_eff_matrix((1:lambda_indx),2));  
        end

        % Value changed function: TiltAngleEditField
        function TiltAngleEditFieldValueChanged(app, event)
            value = app.TiltAngleEditField.Value;  
        end

        % Value changed function: IlluminationAngleEditField
        function IlluminationAngleEditFieldValueChanged(app, event)
            value = app.IlluminationAngleEditField.Value;
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)

        end

        % Callback function
        function ButtonGroupButtonDown(app, event)

        end

        % Callback function
        function ButtonGroupSelectionChanged2(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
             
            if ((app.Gain1Button.Value))
                    imshow(app.diff_patt1,[], 'Parent',app.UIAxes);

               elseif (app.Gain2Button.Value)
                     imshow(app.diff_patt1*3,[], 'Parent',app.UIAxes);
 
               elseif (app.Gain3Button.Value)
                     imshow(app.diff_patt1*5,[], 'Parent',app.UIAxes);

               elseif (app.LogButton.Value)
                     imshow(log(app.diff_patt1),[], 'Parent',app.UIAxes);
            end

            app.UIAxes.XLim = [0 width(app.diff_patt1)];
            app.UIAxes.YLim = [0 height(app.diff_patt1)];
            [rows, columns, numberOfColorChannels] = size(app.diff_patt1);
            x = columns/2;
            y = rows/2;
            array_size = app.ArraySizeEditField.Value;
            centx = (x+(array_size/2+1)) / 2;
            centy = (y+(array_size/2+1)) / 2;
            r = ((array_size/4)+((array_size-1)/100))/app.f_num_proj1;
%             r = 54/app.f_num_proj1;
            hold(app.UIAxes, "on");
            theta = 0 : (2 * pi / 10000) : (2 * pi);
            pline_x = r * cos(theta) + centx;
            pline_y = r * sin(theta) + centy;
             plot(pline_x, pline_y, '--', 'MarkerFaceColor', 'b',  'LineWidth', 3, 'Parent', app.UIAxes); %[0 0.4470 0.7410]
            hold(app.UIAxes, "off");
        end

        % Value changed function: OutputFileNameEditField
        function OutputFileNameEditFieldValueChanged(app, event)
            value = app.OutputFileNameEditField.Value; 
            if value == ' '
                value =  'NA';
            end
        end

        % Value changed function: PhotopicDiffractionEfficiencyTextArea
        function PhotopicDiffractionEfficiencyTextAreaValueChanged(app, event)
            value = app.PhotopicDiffractionEfficiencyTextArea.Value;
            
        end

        % Value changed function: ArraySizeEditField
        function ArraySizeEditFieldValueChanged(app, event)
            value = app.ArraySizeEditField.Value;
            
        end

        % Value changed function: SaveDiffractionPatternDataCheckBox
        function SaveDiffractionPatternDataCheckBoxValueChanged(app, event)
            value = app.SaveDiffractionPatternDataCheckBox.Value;
            
        end

        % Value changed function: EnhanceSlider
        function EnhanceSliderValueChanged(app, event)
            value = app.EnhanceSlider.Value;
            
            imshow(app.diff_patt1*value,[], 'Parent',app.UIAxes);

            app.UIAxes.XLim = [0 width(app.diff_patt1)];
            app.UIAxes.YLim = [0 height(app.diff_patt1)];
            [rows, columns, numberOfColorChannels] = size(app.diff_patt1);
            x = columns/2;
            y = rows/2;
            array_size = app.ArraySizeEditField.Value;
            centx = (x+(array_size/2+1)) / 2;
            centy = (y+(array_size/2+1)) / 2;
            r = ((array_size/4)+((array_size-1)/100))/app.f_num_proj1;
            hold(app.UIAxes, "on");
            theta = 0 : (2 * pi / 10000) : (2 * pi);
            pline_x = r * cos(theta) + centx;
            pline_y = r * sin(theta) + centy;
            plot(pline_x, pline_y, '--', 'MarkerFaceColor', 'b',  'LineWidth', 3, 'Parent', app.UIAxes); %[0 0.4470 0.7410]
            hold(app.UIAxes, "off");
            
        end

        % Drop down opening function: PupilDropDown
        function PupilDropDownOpening(app, event)
            
        end

        % Value changed function: PupilDropDown
        function PupilDropDownValueChanged(app, event)
            value = app.PupilDropDown.Value;

            if strcmp(value, 'Gaussian')
                app.gaussian = 1;
                app.GaussianFactorEditField.Visible = true;
                app.GaussianFactorEditFieldLabel.Visible = true;
                app.UIAxes4.Visible = true;
                cla(app.UIAxes4);
            elseif strcmp(value, 'Uniform')
                app.gaussian = 0;
                app.GaussianFactorEditField.Visible = false;
                app.GaussianFactorEditFieldLabel.Visible = false;
                app.GaussianFactorEditField.Value = 0;
                app.UIAxes4.Visible = true;
                
                cla(app.UIAxes4);
            end
        end

        % Button pushed function: ParameterSweepButton
        function ParameterSweepButtonPushed(app, event)
            if (app.FProjectionStopEditField.Visible==false)
                app.FProjectionStopEditField.Visible = true;
                app.FIlluminationStopEditField.Visible = true;
                app.FIlluminationStepEditField.Visible = true;
                app.FProjectionStepEditField.Visible = true;
                app.TiltAngleStepEditField.Visible = true;
                app.TiltAngleStopEditField.Visible = true;
                app.IlluminationAngleStepEditField.Visible = true;
                app.IlluminationAngleStopEditField.Visible = true;

            else 
                app.FProjectionStopEditField.Visible = false;
                app.FProjectionStopEditField.Visible = false;
                app.FIlluminationStopEditField.Visible = false;
                app.FIlluminationStepEditField.Visible = false;
                app.FProjectionStepEditField.Visible = false;
                app.TiltAngleStepEditField.Visible = false;
                app.TiltAngleStopEditField.Visible = false;
                app.IlluminationAngleStepEditField.Visible = false;
                app.IlluminationAngleStopEditField.Visible = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Diffraction Pattern')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Diffraction Efficiency')
            xlabel(app.UIAxes2, 'Wavelength (nm)')
            ylabel(app.UIAxes2, 'Diffraction Efficiency')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Diffraction Energy')
            xlabel(app.UIAxes3, 'Y (Degrees) ')
            ylabel(app.UIAxes3, 'X (Degrees)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Apodization Profile')
            xlabel(app.UIAxes4, 'Pupil Cross Section (Degrees)')
            ylabel(app.UIAxes4, 'Relative Intensity')
            zlabel(app.UIAxes4, 'Z')
            app.TIDLPDiffractionModelingAppv2Label = uilabel(app.UIFigure);
            app.TIDLPDiffractionModelingAppv2Label.Text = 'TI DLP Diffraction Modeling App - v2';
            app.Panel = uipanel(app.UIFigure);
            app.PixelDropDown = uidropdown(app.Panel);
            app.PixelDropDown.Items = {'4.5 RDP', '5.4 TRP', '5.4 SST, Tilt 12', '5.4 SST, Tilt 14.5', '7.56 VSP', '7.637 VSP', '9 HEP', '10.8 SPD', '13.68 FTP'};
            app.PixelDropDown.ValueChangedFcn = createCallbackFcn(app, @PixelDropDownValueChanged, true);
            app.PixelDropDown.Value = '4.5 RDP';
            app.PixelModelLabel = uilabel(app.Panel);
            app.PixelModelLabel.Text = 'Pixel';
            app.ArraySizeEditFieldLabel = uilabel(app.Panel);
            app.ArraySizeEditFieldLabel.Text = 'Array Size';
            app.ArraySizeEditField = uieditfield(app.Panel, 'numeric');
            app.ArraySizeEditField.ValueChangedFcn = createCallbackFcn(app, @ArraySizeEditFieldValueChanged, true);
            app.ArraySizeEditField.Value = 201;
            app.StartWavelengthnmEditField = uieditfield(app.Panel, 'numeric');
            app.StartWavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @StartWavelengthnmEditFieldValueChanged, true);
            app.StartWavelengthnmEditField.Value = 400;
            app.EndWavelengthnmEditField = uieditfield(app.Panel, 'numeric');
            app.EndWavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @EndWavelengthnmEditFieldValueChanged, true);
            app.EndWavelengthnmEditField.Value = 700;
            app.WavelengthStepnmEditField = uieditfield(app.Panel, 'numeric');
            app.WavelengthStepnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthStepnmEditFieldValueChanged, true);
            app.WavelengthStepnmEditField.Value = 10;
            app.IlluminationAngleLabel = uilabel(app.Panel);
            app.IlluminationAngleLabel.Text = 'Illumination Angleº';
            app.IlluminationAngleEditField = uieditfield(app.Panel, 'numeric');
            app.IlluminationAngleEditField.Limits = [-90 90];
            app.IlluminationAngleEditField.ValueChangedFcn = createCallbackFcn(app, @IlluminationAngleEditFieldValueChanged, true);
            app.IlluminationAngleEditField.Value = 29;
            app.IlluminationAngleStopEditField = uieditfield(app.Panel, 'numeric');
            app.IlluminationAngleStopEditField.Visible = 'off';
            app.IlluminationAngleStopEditField.Value = 29;
            app.IlluminationAngleStepEditField = uieditfield(app.Panel, 'numeric');
            app.IlluminationAngleStepEditField.Visible = 'off';
            app.IlluminationAngleStepEditField.Value = 1;
            app.TiltAngleEditField = uieditfield(app.Panel, 'numeric');
            app.TiltAngleEditField.Limits = [-90 90];
            app.TiltAngleEditField.ValueChangedFcn = createCallbackFcn(app, @TiltAngleEditFieldValueChanged, true);
            app.TiltAngleEditField.Value = 14.5;
            app.TiltAngleStopEditField = uieditfield(app.Panel, 'numeric');
            app.TiltAngleStopEditField.Visible = 'off';
            app.TiltAngleStopEditField.Value = 14.5;
            app.TiltAngleStepEditField = uieditfield(app.Panel, 'numeric');
            app.TiltAngleStepEditField.Visible = 'off';
            app.TiltAngleStepEditField.Value = 1;
            app.FLabel = uilabel(app.Panel);
            app.FLabel.Text = 'F / # (Illumination)   ';
            app.FIlluminationEditField = uieditfield(app.Panel, 'numeric');
            app.FIlluminationEditField.Limits = [0 Inf];
            app.FIlluminationEditField.ValueChangedFcn = createCallbackFcn(app, @FIlluminationEditFieldValueChanged, true);
            app.FIlluminationEditField.Value = 2;
            app.FIlluminationStopEditField = uieditfield(app.Panel, 'numeric');
            app.FIlluminationStopEditField.Visible = 'off';
            app.FIlluminationStopEditField.Value = 2;
            app.FIlluminationStepEditField = uieditfield(app.Panel, 'numeric');
            app.FIlluminationStepEditField.Visible = 'off';
            app.FIlluminationStepEditField.Value = 1;
            app.TiltAngleLabel = uilabel(app.Panel);
            app.TiltAngleLabel.Text = 'Tilt Angleº';
            app.FProjectionEditFieldLabel = uilabel(app.Panel);
            app.FProjectionEditFieldLabel.Text = 'F / # (Projection) ';
            app.FProjectionEditField = uieditfield(app.Panel, 'numeric');
            app.FProjectionEditField.Limits = [0 Inf];
            app.FProjectionEditField.ValueChangedFcn = createCallbackFcn(app, @FProjectionEditFieldValueChanged, true);
            app.FProjectionEditField.Value = 2;
            app.FProjectionStopEditField = uieditfield(app.Panel, 'numeric');
            app.FProjectionStopEditField.Visible = 'off';
            app.FProjectionStopEditField.Value = 2;
            app.FProjectionStepEditField = uieditfield(app.Panel, 'numeric');
            app.FProjectionStepEditField.Visible = 'off';
            app.FProjectionStepEditField.Value = 1;
            app.OutputFileNameEditField = uieditfield(app.Panel, 'text');
            app.OutputFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputFileNameEditFieldValueChanged, true);
            app.OutputFileNameEditField.Value = 'Test_1';
            app.PhotopicDiffractionEfficiencyTextAreaLabel = uilabel(app.Panel);
            app.PhotopicDiffractionEfficiencyTextAreaLabel.Text = 'Photopic Diffraction Efficiency';
            app.PowerRatioResultLabel = uilabel(app.Panel);
            app.PowerRatioResultLabel.Text = 'Average Diffraction Efficiency';
            app.OutputFileNameEditFieldLabel = uilabel(app.Panel);
            app.OutputFileNameEditFieldLabel.Text = 'Output File Name';
            app.SaveDiffractionPatternDataCheckBox = uicheckbox(app.Panel);
            app.SaveDiffractionPatternDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDiffractionPatternDataCheckBoxValueChanged, true);
            app.SaveDiffractionPatternDataCheckBox.Text = 'Save Diffraction Pattern Data?';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AverageDiffractionEfficiencyTextArea = uitextarea(app.Panel);
            app.AverageDiffractionEfficiencyTextArea.ValueChangedFcn = createCallbackFcn(app, @AverageDiffractionEfficiencyTextAreaValueChanged, true);
            app.PhotopicDiffractionEfficiencyTextArea = uitextarea(app.Panel);
            app.PhotopicDiffractionEfficiencyTextArea.ValueChangedFcn = createCallbackFcn(app, @PhotopicDiffractionEfficiencyTextAreaValueChanged, true);
            app.StartLabel = uilabel(app.Panel);
            app.StartLabel.Text = 'Start';
            app.StopLabel = uilabel(app.Panel);
            app.StopLabel.Text = 'Stop';
            app.StepLabel = uilabel(app.Panel);
            app.StepLabel.Text = 'Step';
            app.ParametersLabel = uilabel(app.Panel);
            app.ParametersLabel.Text = 'Parameters';
            app.WavelengthnmLabel = uilabel(app.Panel);
            app.WavelengthnmLabel.Text = 'Wavelength (nm)';
            app.PupilDropDownLabel = uilabel(app.Panel);
            app.PupilDropDownLabel.Text = 'Pupil';
            app.PupilDropDown = uidropdown(app.Panel);
            app.PupilDropDown.Items = {'Uniform', 'Gaussian'};
            app.PupilDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PupilDropDownOpening, true);
            app.PupilDropDown.ValueChangedFcn = createCallbackFcn(app, @PupilDropDownValueChanged, true);
            app.PupilDropDown.Value = 'Uniform';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ManhattanButton = uiradiobutton(app.ButtonGroup);
            app.ManhattanButton.Text = 'Manhattan';
            app.ManhattanButton.Value = true;
            app.DiamondButton = uiradiobutton(app.ButtonGroup);
            app.DiamondButton.Text = 'Diamond';
            app.GaussianFactorEditFieldLabel = uilabel(app.Panel);
            app.GaussianFactorEditFieldLabel.Visible = 'off';
            app.GaussianFactorEditFieldLabel.Text = 'Gaussian Factor';
            app.GaussianFactorEditField = uieditfield(app.Panel, 'numeric');
            app.GaussianFactorEditField.Limits = [0 Inf];
            app.GaussianFactorEditField.Visible = 'off';
            app.ParameterSweepButton = uibutton(app.Panel, 'push');
            app.ParameterSweepButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterSweepButtonPushed, true);
            app.ParameterSweepButton.Text = 'Parameter Sweep';
            app.EnhanceSliderLabel = uilabel(app.UIFigure);
            app.EnhanceSliderLabel.Text = 'Enhance';
            app.EnhanceSlider = uislider(app.UIFigure);
            app.EnhanceSlider.Limits = [0 10];
            app.EnhanceSlider.ValueChangedFcn = createCallbackFcn(app, @EnhanceSliderValueChanged, true);
            app.EnhanceSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DMD_Diffraction_Efficiency_Calculator_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end